classdef MainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FilePanel                       matlab.ui.container.Panel
        OpenButton                      matlab.ui.control.Button
        SaveCalculationDataButton       matlab.ui.control.Button
        ROIPanel                        matlab.ui.container.Panel
        ShapeDropDownLabel              matlab.ui.control.Label
        ShapeDropDown                   matlab.ui.control.DropDown
        DeleteROIButton                 matlab.ui.control.Button
        MeasurementPanel                matlab.ui.container.Panel
        MeasureAnteriorROIButton        matlab.ui.control.Button
        MeasurePosteriorROIButton       matlab.ui.control.Button
        ShowresultsforAnteriorViewCheckBox  matlab.ui.control.CheckBox
        ShowresultsforPosteriorViewCheckBox  matlab.ui.control.CheckBox
        MeasureAdjacentAnteriorROIButton  matlab.ui.control.Button
        ShowresultsforAdjacentAnteriorCheckBox  matlab.ui.control.CheckBox
        MeasureAdjacentPosteriorROIButton  matlab.ui.control.Button
        ShowresultsforAdjacentPosteriorCheckBox  matlab.ui.control.CheckBox
        BackButton                      matlab.ui.control.Button
        ActivityCalculationPanel        matlab.ui.container.Panel
        CalibrationFactorEditFieldLabel  matlab.ui.control.Label
        CalibrationFactorEditField      matlab.ui.control.NumericEditField
        PatientThicknessEditFieldLabel  matlab.ui.control.Label
        PatientThicknessEditField       matlab.ui.control.NumericEditField
        AttenuationFactorEditFieldLabel  matlab.ui.control.Label
        AttenuationFactorEditField      matlab.ui.control.NumericEditField
        CorrectionFactorEditFieldLabel  matlab.ui.control.Label
        CorrectionFactorEditField       matlab.ui.control.NumericEditField
        CalculateActivityButton         matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        MeasurementResultsPanel         matlab.ui.container.Panel
        AnteriorPanel                   matlab.ui.container.Panel
        MPVAEditFieldLabel              matlab.ui.control.Label
        MPVAEditField                   matlab.ui.control.NumericEditField
        SumAEditFieldLabel              matlab.ui.control.Label
        SumAEditField                   matlab.ui.control.NumericEditField
        SDAEditFieldLabel               matlab.ui.control.Label
        SDAEditField                    matlab.ui.control.NumericEditField
        AreaAEditFieldLabel             matlab.ui.control.Label
        AreaAEditField                  matlab.ui.control.NumericEditField
        IAEditFieldLabel                matlab.ui.control.Label
        IAEditField                     matlab.ui.control.NumericEditField
        PosteriorPanel                  matlab.ui.container.Panel
        MPVPEditFieldLabel              matlab.ui.control.Label
        MPVPEditField                   matlab.ui.control.NumericEditField
        SumPEditFieldLabel              matlab.ui.control.Label
        SumPEditField                   matlab.ui.control.NumericEditField
        SDPEditFieldLabel               matlab.ui.control.Label
        SDPEditField                    matlab.ui.control.NumericEditField
        AreaPEditFieldLabel             matlab.ui.control.Label
        AreaPEditField                  matlab.ui.control.NumericEditField
        IPEditFieldLabel                matlab.ui.control.Label
        IPEditField                     matlab.ui.control.NumericEditField
        AdjacentAnteriorPanel           matlab.ui.container.Panel
        MPVBAEditFieldLabel             matlab.ui.control.Label
        MPVBAEditField                  matlab.ui.control.NumericEditField
        SumBAEditFieldLabel             matlab.ui.control.Label
        SumBAEditField                  matlab.ui.control.NumericEditField
        SDBAEditFieldLabel              matlab.ui.control.Label
        SDBAEditField                   matlab.ui.control.NumericEditField
        AreaBAEditFieldLabel            matlab.ui.control.Label
        AreaBAEditField                 matlab.ui.control.NumericEditField
        IBAEditFieldLabel               matlab.ui.control.Label
        IBAEditField                    matlab.ui.control.NumericEditField
        AdjacentPosteriorPanel          matlab.ui.container.Panel
        MPVBPEditFieldLabel             matlab.ui.control.Label
        MPVBPEditField                  matlab.ui.control.NumericEditField
        SumBPEditFieldLabel             matlab.ui.control.Label
        SumBPEditField                  matlab.ui.control.NumericEditField
        SDBPEditFieldLabel              matlab.ui.control.Label
        SDBPEditField                   matlab.ui.control.NumericEditField
        AreaBPEditFieldLabel            matlab.ui.control.Label
        AreaBPEditField                 matlab.ui.control.NumericEditField
        IBPEditFieldLabel               matlab.ui.control.Label
        IBPEditField                    matlab.ui.control.NumericEditField
        ClearResultsButton              matlab.ui.control.Button
        ScanDurationEditFieldLabel      matlab.ui.control.Label
        ScanDurationEditField           matlab.ui.control.NumericEditField
        OrganEditFieldLabel             matlab.ui.control.Label
        OrganEditField                  matlab.ui.control.EditField
        SeeformulaButton                matlab.ui.control.Button
        ActivityEditFieldLabel          matlab.ui.control.Label
        ActivityEditField               matlab.ui.control.NumericEditField
        TimePointEditFieldLabel         matlab.ui.control.Label
        TimePointEditField              matlab.ui.control.EditField
        ZoomSwitchLabel                 matlab.ui.control.Label
        ZoomSwitch                      matlab.ui.control.Switch
        FlippedAnteriorPosteriorPanel   matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        Button                          matlab.ui.control.Button
    end

    
    properties (Access = public)
        I
        h
        BW
        roi
        front
        back
        Spacing
        IA
        IP
        CRF
        AF
        CLF
        PT
        IBP
        IBA   
        e
        Aj
    end


    methods (Access = public)
        
         function MPVA(app)
          app.MPVAEditField.Value= mean(app.e(app.BW));
             end

            function SDA(app)
          app.SDAEditField.Value= std(double(app.e(app.BW)));
                 end
            
                 function sumA(app)
           app.SumAEditField.Value = numel(app.e(app.BW));
                 end
                     
                 function AreaA(app)
                     areaA= app.Spacing*numel(app.e(app.BW))
                   app.AreaAEditField.Value = areaA
                 end
                 
                 function MPVP(app)
          app.MPVPEditField.Value= mean(app.e(app.BW));
          
             end

            function SDP(app)
          app.SDPEditField.Value= std(double(app.e(app.BW)));
                 end
            
                 function sumP(app)
           app.SumPEditField.Value = numel(app.e(app.BW));
                 end
                     
                 function AreaP(app)
                     areaP= app.Spacing*numel(app.e(app.BW))
                   app.AreaPEditField.Value = areaP
                 end
                 
                 function CountsAnterior(app)
                    app.IA = (mean(app.e(app.BW))*numel(app.e(app.BW)))/app.ScanDurationEditField.Value;
                    app.IAEditField.Value = double(app.IA)
                 end
                 
                 function CountsPosterior(app)
                    app.IP= (mean(app.e(app.BW))*numel(app.e(app.BW)))/app.ScanDurationEditField.Value
                   app.IPEditField.Value = double(app.IP)
                 end
                 
                 
        function MPVBA(app)
          app.MPVBAEditField.Value= mean(app.e(app.BW));
             end

            function SDBA(app)
          app.SDBAEditField.Value= std(double(app.e(app.BW)));
                 end
            
                 function sumBA(app)
            app.SumBAEditField.Value = numel(app.e(app.BW));
                 end
                     
                 function AreaBA(app)
                     areaBA= app.Spacing*numel(app.e(app.BW))
                   app.AreaBAEditField.Value = areaBA
                 end
                 
                 function CountsBA(app)
                 app.IBA = (mean(app.e(app.BW))*numel(app.e(app.BW)))/app.ScanDurationEditField.Value;
                 app.IBAEditField.Value = double(app.IBA)
                 end
                
                  function MPVBP(app)
          app.MPVBPEditField.Value= mean(app.e(app.BW));
             end

            function SDBP(app)
          app.SDBPEditField.Value= std(double(app.e(app.BW)));
                 end
            
                 function sumBP(app)
            app.SumBPEditField.Value = numel(app.e(app.BW));
                 end
                     
                 function AreaBP(app)
                     areaBP= app.Spacing*numel(app.e(app.BW))
                   app.AreaBPEditField.Value = areaBP
                 end
                 
                 function CountsBP(app)
                 app.IBP= mean((app.e(app.BW))*numel(app.e(app.BW)))/app.ScanDurationEditField.Value;
                 app.IBPEditField.Value = double(app.IBP)
                 end
  
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
   app.AnteriorPanel.Visible = 'off' ;    
   app.PosteriorPanel.Visible = 'off' ;   
   app.AdjacentAnteriorPanel.Visible = 'off';
    app.AdjacentPosteriorPanel.Visible = 'off';
  
           app.UIAxes.Toolbar.Visible = 'off'
    disableDefaultInteractivity(app.UIAxes);
   
    
    C= magic(3)
    axis(app.UIAxes,'tight')
    im = imagesc(app.UIAxes,C)
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            [file,path] = uigetfile({'All Files (*.*)'}, ...
   'Select a file to continue',...
   'Select a file');
if ischar(path)
selectedfile = fullfile(path,file);
info = dicominfo(selectedfile);
        app.I = dicomread(info)
         S = info.PixelSpacing;
app.Spacing= S(1)*S(2)

app.front=flipdim(app.I(:,:,:,1),2)
app.back= app.I(:,:,:,2)   
        
app.e= cat(2,app.front,app.back)
imshow(app.e, [],'Parent', app.UIAxes)

end
        end

        % Button pushed function: MeasureAnteriorROIButton
        function MeasureAnteriorROIButtonPushed(app, event)
app.BW= createMask(app.h)
app.MPVA
app.SDA
app.AreaA
app.sumA
app.CountsAnterior
        end

        % Value changed function: MPVAEditField
        function MPVAEditFieldValueChanged(app, event)
   
        end

        % Value changed function: SumAEditField
        function SumAEditFieldValueChanged(app, event)
 
        end

        % Value changed function: SDAEditField
        function SDAEditFieldValueChanged(app, event)
          
        end

        % Value changed function: AreaAEditField
        function AreaAEditFieldValueChanged(app, event)
    
        end

        % Value changed function: MPVPEditField
        function MPVPEditFieldValueChanged(app, event)
            
            
        end

        % Value changed function: SumPEditField
        function SumPEditFieldValueChanged(app, event)
           
            
        end

        % Value changed function: SDPEditField
        function SDPEditFieldValueChanged(app, event)
            
            
        end

        % Value changed function: AreaPEditField
        function AreaPEditFieldValueChanged(app, event)
            
            
        end

        % Value changed function: ShapeDropDown
        function ShapeDropDownValueChanged(app, event)
           app.roi= app.ShapeDropDown.Value;

           switch app.roi
                case 'Circle'
              app.h= drawcircle(app.UIAxes,'StripeColor', 'y', 'LineWidth', 1)
                case 'Freehand'
                app.h= drawfreehand(app.UIAxes,'StripeColor', 'y', 'LineWidth', 1)
                case 'Rectangle' 
                 app.h=drawrectangle(app.UIAxes,'StripeColor', 'y', 'LineWidth', 1)
            end
      
        end

        % Value changed function: ShowresultsforAnteriorViewCheckBox
        function ShowresultsforAnteriorViewCheckBoxValueChanged(app, event)
            value = app.ShowresultsforAnteriorViewCheckBox.Value;
            
            switch value
                case 0 
                 app.AnteriorPanel.Visible = 'off';
                 case 1
                 app.AnteriorPanel.Visible = 'on';
                 
            end
        end

        % Value changed function: 
        % ShowresultsforPosteriorViewCheckBox
        function ShowresultsforPosteriorViewCheckBoxValueChanged(app, event)
            value = app.ShowresultsforPosteriorViewCheckBox.Value;
           
            switch value
                case 0 
                 app.PosteriorPanel.Visible = 'off';
                 case 1
                 app.PosteriorPanel.Visible = 'on';
                 
            end
        end

        % Button pushed function: MeasurePosteriorROIButton
        function MeasurePosteriorROIButtonPushed(app, event)
app.BW= createMask(app.h);
app.MPVP
app.SDP
app.AreaP
app.sumP
app.CountsPosterior
        end

        % Value changed function: IAEditField
        function IAEditFieldValueChanged(app, event)

            
        end

        % Value changed function: IPEditField
        function IPEditFieldValueChanged(app, event)
      
        end

        % Button pushed function: DeleteROIButton
        function DeleteROIButtonPushed(app, event)
            t= findobj(app.h);
            delete(t)
           
        end

        % Button pushed function: CalculateActivityButton
        function CalculateActivityButtonPushed(app, event)
app.Aj  = sqrt( (app.IA-app.IBA)*(app.IP-app.IBP) / (exp(-app.AF*app.PT))) * (app.CRF / app.CLF);
app.ActivityEditField.Value = double(app.Aj)
        end

        % Value changed function: CalibrationFactorEditField
        function CalibrationFactorEditFieldValueChanged(app, event)
            app.CLF = app.CalibrationFactorEditField.Value;
            
        end

        % Value changed function: PatientThicknessEditField
        function PatientThicknessEditFieldValueChanged(app, event)
            app.PT = app.PatientThicknessEditField.Value;
            
        end

        % Value changed function: AttenuationFactorEditField
        function AttenuationFactorEditFieldValueChanged(app, event)
            app.AF = app.AttenuationFactorEditField.Value;
            
        end

        % Value changed function: CorrectionFactorEditField
        function CorrectionFactorEditFieldValueChanged(app, event)
            app.CRF = app.CorrectionFactorEditField.Value;
            
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            delete(app)
            run 'StartMenu.mlapp' 
        end

        % Button pushed function: SaveCalculationDataButton
        function SaveCalculationDataButtonPushed(app, event)
             
[file,path]= uiputfile('*.xlsx','.xls')
if isequal(file,0) || isequal(path,0)
    disp('User clicked cancel')
else
filename = fullfile(path,file)

Parameters= {'MPV';...
    'Sum';...
    'SD';...
    'Area';...
    'Counts'}
a= cell2table(Parameters)
Anterior = [app.MPVAEditField.Value;app.SumAEditField.Value;app.SDAEditField.Value;app.AreaAEditField.Value; app.IAEditField.Value];
Posterior = [app.MPVPEditField.Value;app.SumPEditField.Value;app.SDPEditField.Value;app.AreaPEditField.Value; app.IPEditField.Value];
Adjacent_Anterior = [app.MPVBAEditField.Value;app.SumBAEditField.Value;app.SDBAEditField.Value;app.AreaBAEditField.Value; app.IBAEditField.Value];
Adjacent_Posterior = [app.MPVBPEditField.Value;app.SumBPEditField.Value;app.SDBPEditField.Value;app.AreaBPEditField.Value; app.IBPEditField.Value];

T = cell2table({'Organ' 'Time Point (h)' 'Scan Duration' 'Calibration Factor' 'Patient Thickness' 'Attenuation Factor' 'Correction Factor' 'Calculated Activity';...
    app.OrganEditField.Value, app.TimePointEditField.Value, app.ScanDurationEditField.Value, app.CalibrationFactorEditField.Value,...
    app.PatientThicknessEditField.Value,app.AttenuationFactorEditField.Value,...
    app.CorrectionFactorEditField.Value, app.ActivityEditField.Value})

b = table(Anterior, Posterior, Adjacent_Anterior, Adjacent_Posterior)
all = [a, b]   

writetable(T, filename,'Sheet',1) %before save the file make sure that the .xlsx file;
writetable(all, filename,'Sheet',2)

end

        end

        % Button pushed function: MeasureAdjacentAnteriorROIButton
        function MeasureAdjacentAnteriorROIButtonPushed(app, event)
app.BW= createMask(app.h)
app.MPVBA
app.SDBA
app.AreaBA
app.sumBA
app.CountsBA
        end

        % Value changed function: MPVBAEditField
        function MPVBAEditFieldValueChanged(app, event)
          
        end

        % Value changed function: SumBAEditField
        function SumBAEditFieldValueChanged(app, event)
           
            
        end

        % Value changed function: SDBAEditField
        function SDBAEditFieldValueChanged(app, event)
  
        end

        % Value changed function: AreaBAEditField
        function AreaBAEditFieldValueChanged(app, event)
  
        end

        % Value changed function: IBAEditField
        function IBAEditFieldValueChanged(app, event)

            
        end

        % Value changed function: 
        % ShowresultsforAdjacentAnteriorCheckBox
        function ShowresultsforAdjacentAnteriorCheckBoxValueChanged(app, event)
             value = app.ShowresultsforAdjacentAnteriorCheckBox.Value;
            switch value
                case 0 
                 app.AdjacentAnteriorPanel.Visible = 'off';
                 case 1
                 app.AdjacentAnteriorPanel.Visible = 'on';
           end
        end

        % Value changed function: 
        % ShowresultsforAdjacentPosteriorCheckBox
        function ShowresultsforAdjacentPosteriorCheckBoxValueChanged(app, event)
            value = app.ShowresultsforAdjacentPosteriorCheckBox.Value;
            
            switch value
                case 0 
                 app.AdjacentPosteriorPanel.Visible = 'off';
                 case 1
                 app.AdjacentPosteriorPanel.Visible = 'on';
           end
        end

        % Value changed function: MPVBPEditField
        function MPVBPEditFieldValueChanged(app, event)
            value = app.MPVBPEditField.Value;
            
        end

        % Value changed function: SumBPEditField
        function SumBPEditFieldValueChanged(app, event)
  
            
        end

        % Value changed function: SDBPEditField
        function SDBPEditFieldValueChanged(app, event)
            value = app.SDBPEditField.Value;
            
        end

        % Value changed function: AreaBPEditField
        function AreaBPEditFieldValueChanged(app, event)
     
            
        end

        % Value changed function: IBPEditField
        function IBPEditFieldValueChanged(app, event)

            
        end

        % Button pushed function: MeasureAdjacentPosteriorROIButton
        function MeasureAdjacentPosteriorROIButtonPushed(app, event)
app.BW= createMask(app.h)
app.MPVBP
app.SDBP
app.AreaBP
app.sumBP
app.CountsBP
        end

        % Value changed function: OrganEditField
        function OrganEditFieldValueChanged(app, event)
         
            
        end

        % Value changed function: ScanDurationEditField
        function ScanDurationEditFieldValueChanged(app, event)
         
            
        end

        % Button pushed function: SeeformulaButton
        function SeeformulaButtonPushed(app, event)
            run Formula.mlapp
        end

        % Value changed function: ActivityEditField
        function ActivityEditFieldValueChanged(app, event)
     
        end

        % Value changed function: TimePointEditField
        function TimePointEditFieldValueChanged(app, event)
           
        end

        % Value changed function: ZoomSwitch
        function ZoomSwitchValueChanged(app, event)
            value = app.ZoomSwitch.Value;
  
            switch value
                case 'On'
                enableDefaultInteractivity(app.UIAxes)
                axtoolbar(app.UIAxes,{'zoomin','zoomout','restoreview', 'pan'});
                app.UIAxes.Toolbar.Visible = 'on'
                pan(app.UIAxes,'off')   
                case 'Off'
                   disableDefaultInteractivity(app.UIAxes)
                   app.UIAxes.Toolbar.Visible = 'off' 
               
            end
            
        end

        % Button pushed function: ClearResultsButton
        function ClearResultsButtonPushed(app, event)
app.MPVAEditField.Value = 0 
app.SumAEditField.Value = 0
app.SDAEditField.Value = 0
app.AreaAEditField.Value = 0 
app.IAEditField.Value = 0
app.MPVPEditField.Value = 0
app.SumPEditField.Value = 0
app.SDPEditField.Value = 0
app.AreaPEditField.Value = 0
app.IPEditField.Value = 0
app.MPVBAEditField.Value = 0
app.SumBAEditField.Value = 0
app.SDBAEditField.Value = 0
app.AreaBAEditField.Value = 0
app.IBAEditField.Value = 0
app.MPVBPEditField.Value = 0
app.SumBPEditField.Value = 0
app.SDBPEditField.Value = 0
app.AreaBPEditField.Value = 0
app.IBPEditField.Value = 0
app.ActivityEditField.Value = 0        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FilePanel = uipanel(app.UIFigure);
            app.FilePanel.Title = 'File';
            app.OpenButton = uibutton(app.FilePanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.SaveCalculationDataButton = uibutton(app.FilePanel, 'push');
            app.SaveCalculationDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCalculationDataButtonPushed, true);
            app.SaveCalculationDataButton.Text = 'Save Calculation Data';
            app.ROIPanel = uipanel(app.UIFigure);
            app.ROIPanel.Title = 'ROI';
            app.ShapeDropDownLabel = uilabel(app.ROIPanel);
            app.ShapeDropDownLabel.Text = 'Shape';
            app.ShapeDropDown = uidropdown(app.ROIPanel);
            app.ShapeDropDown.Items = {'Choose to start', 'Circle', 'Freehand', 'Rectangle'};
            app.ShapeDropDown.ValueChangedFcn = createCallbackFcn(app, @ShapeDropDownValueChanged, true);
            app.ShapeDropDown.Value = 'Choose to start';
            app.DeleteROIButton = uibutton(app.ROIPanel, 'push');
            app.DeleteROIButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteROIButtonPushed, true);
            app.DeleteROIButton.Text = 'Delete ROI';
            app.MeasurementPanel = uipanel(app.UIFigure);
            app.MeasurementPanel.Title = 'Measurement';
            app.MeasureAnteriorROIButton = uibutton(app.MeasurementPanel, 'push');
            app.MeasureAnteriorROIButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureAnteriorROIButtonPushed, true);
            app.MeasureAnteriorROIButton.Text = 'Measure Anterior ROI';
            app.MeasurePosteriorROIButton = uibutton(app.MeasurementPanel, 'push');
            app.MeasurePosteriorROIButton.ButtonPushedFcn = createCallbackFcn(app, @MeasurePosteriorROIButtonPushed, true);
            app.MeasurePosteriorROIButton.Text = 'Measure Posterior ROI';
            app.ShowresultsforAnteriorViewCheckBox = uicheckbox(app.MeasurementPanel);
            app.ShowresultsforAnteriorViewCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowresultsforAnteriorViewCheckBoxValueChanged, true);
            app.ShowresultsforAnteriorViewCheckBox.Text = 'Show results for Anterior View';
            app.ShowresultsforPosteriorViewCheckBox = uicheckbox(app.MeasurementPanel);
            app.ShowresultsforPosteriorViewCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowresultsforPosteriorViewCheckBoxValueChanged, true);
            app.ShowresultsforPosteriorViewCheckBox.Text = 'Show results for Posterior View';
            app.MeasureAdjacentAnteriorROIButton = uibutton(app.MeasurementPanel, 'push');
            app.MeasureAdjacentAnteriorROIButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureAdjacentAnteriorROIButtonPushed, true);
            app.MeasureAdjacentAnteriorROIButton.Text = 'Measure Adjacent Anterior ROI';
            app.ShowresultsforAdjacentAnteriorCheckBox = uicheckbox(app.MeasurementPanel);
            app.ShowresultsforAdjacentAnteriorCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowresultsforAdjacentAnteriorCheckBoxValueChanged, true);
            app.ShowresultsforAdjacentAnteriorCheckBox.Text = 'Show results for Adjacent Anterior';
            app.MeasureAdjacentPosteriorROIButton = uibutton(app.MeasurementPanel, 'push');
            app.MeasureAdjacentPosteriorROIButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureAdjacentPosteriorROIButtonPushed, true);
            app.MeasureAdjacentPosteriorROIButton.Text = 'Measure Adjacent Posterior ROI';
            app.ShowresultsforAdjacentPosteriorCheckBox = uicheckbox(app.MeasurementPanel);
            app.ShowresultsforAdjacentPosteriorCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowresultsforAdjacentPosteriorCheckBoxValueChanged, true);
            app.ShowresultsforAdjacentPosteriorCheckBox.Text = 'Show results for Adjacent Posterior';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.ActivityCalculationPanel = uipanel(app.UIFigure);
            app.ActivityCalculationPanel.Title = 'Activity Calculation';
            app.CalibrationFactorEditFieldLabel = uilabel(app.ActivityCalculationPanel);
            app.CalibrationFactorEditFieldLabel.Text = 'Calibration Factor ';
            app.CalibrationFactorEditField = uieditfield(app.ActivityCalculationPanel, 'numeric');
            app.CalibrationFactorEditField.ValueDisplayFormat = '%.3f';
            app.CalibrationFactorEditField.ValueChangedFcn = createCallbackFcn(app, @CalibrationFactorEditFieldValueChanged, true);
            app.PatientThicknessEditFieldLabel = uilabel(app.ActivityCalculationPanel);
            app.PatientThicknessEditFieldLabel.Text = 'Patient Thickness ';
            app.PatientThicknessEditField = uieditfield(app.ActivityCalculationPanel, 'numeric');
            app.PatientThicknessEditField.ValueDisplayFormat = '%.3f';
            app.PatientThicknessEditField.ValueChangedFcn = createCallbackFcn(app, @PatientThicknessEditFieldValueChanged, true);
            app.AttenuationFactorEditFieldLabel = uilabel(app.ActivityCalculationPanel);
            app.AttenuationFactorEditFieldLabel.Text = 'Attenuation Factor';
            app.AttenuationFactorEditField = uieditfield(app.ActivityCalculationPanel, 'numeric');
            app.AttenuationFactorEditField.ValueDisplayFormat = '%.3f';
            app.AttenuationFactorEditField.ValueChangedFcn = createCallbackFcn(app, @AttenuationFactorEditFieldValueChanged, true);
            app.CorrectionFactorEditFieldLabel = uilabel(app.ActivityCalculationPanel);
            app.CorrectionFactorEditFieldLabel.Text = 'Correction Factor';
            app.CorrectionFactorEditField = uieditfield(app.ActivityCalculationPanel, 'numeric');
            app.CorrectionFactorEditField.ValueDisplayFormat = '%.3f';
            app.CorrectionFactorEditField.ValueChangedFcn = createCallbackFcn(app, @CorrectionFactorEditFieldValueChanged, true);
            app.CalculateActivityButton = uibutton(app.ActivityCalculationPanel, 'push');
            app.CalculateActivityButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateActivityButtonPushed, true);
            app.CalculateActivityButton.Text = 'Calculate Activity ';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.MeasurementResultsPanel = uipanel(app.UIFigure);
            app.MeasurementResultsPanel.Title = 'Measurement Results';
            app.AnteriorPanel = uipanel(app.MeasurementResultsPanel);
            app.AnteriorPanel.Title = 'Anterior';
            app.MPVAEditFieldLabel = uilabel(app.AnteriorPanel);
            app.MPVAEditFieldLabel.Text = 'MPV A';
            app.MPVAEditField = uieditfield(app.AnteriorPanel, 'numeric');
            app.MPVAEditField.ValueDisplayFormat = '%.3f';
            app.MPVAEditField.ValueChangedFcn = createCallbackFcn(app, @MPVAEditFieldValueChanged, true);
            app.SumAEditFieldLabel = uilabel(app.AnteriorPanel);
            app.SumAEditFieldLabel.Text = 'Sum A ';
            app.SumAEditField = uieditfield(app.AnteriorPanel, 'numeric');
            app.SumAEditField.ValueDisplayFormat = '%.3f';
            app.SumAEditField.ValueChangedFcn = createCallbackFcn(app, @SumAEditFieldValueChanged, true);
            app.SDAEditFieldLabel = uilabel(app.AnteriorPanel);
            app.SDAEditFieldLabel.Text = 'SD A';
            app.SDAEditField = uieditfield(app.AnteriorPanel, 'numeric');
            app.SDAEditField.ValueDisplayFormat = '%.3f';
            app.SDAEditField.ValueChangedFcn = createCallbackFcn(app, @SDAEditFieldValueChanged, true);
            app.AreaAEditFieldLabel = uilabel(app.AnteriorPanel);
            app.AreaAEditFieldLabel.Text = 'Area A';
            app.AreaAEditField = uieditfield(app.AnteriorPanel, 'numeric');
            app.AreaAEditField.ValueDisplayFormat = '%.3f';
            app.AreaAEditField.ValueChangedFcn = createCallbackFcn(app, @AreaAEditFieldValueChanged, true);
            app.IAEditFieldLabel = uilabel(app.AnteriorPanel);
            app.IAEditFieldLabel.Text = 'IA';
            app.IAEditField = uieditfield(app.AnteriorPanel, 'numeric');
            app.IAEditField.ValueDisplayFormat = '%.3f';
            app.IAEditField.ValueChangedFcn = createCallbackFcn(app, @IAEditFieldValueChanged, true);
            app.PosteriorPanel = uipanel(app.MeasurementResultsPanel);
            app.PosteriorPanel.Title = 'Posterior ';
            app.MPVPEditFieldLabel = uilabel(app.PosteriorPanel);
            app.MPVPEditFieldLabel.Text = 'MPV P';
            app.MPVPEditField = uieditfield(app.PosteriorPanel, 'numeric');
            app.MPVPEditField.ValueDisplayFormat = '%.3f';
            app.MPVPEditField.ValueChangedFcn = createCallbackFcn(app, @MPVPEditFieldValueChanged, true);
            app.SumPEditFieldLabel = uilabel(app.PosteriorPanel);
            app.SumPEditFieldLabel.Text = 'Sum P ';
            app.SumPEditField = uieditfield(app.PosteriorPanel, 'numeric');
            app.SumPEditField.ValueDisplayFormat = '%.3f';
            app.SumPEditField.ValueChangedFcn = createCallbackFcn(app, @SumPEditFieldValueChanged, true);
            app.SDPEditFieldLabel = uilabel(app.PosteriorPanel);
            app.SDPEditFieldLabel.Text = 'SD P';
            app.SDPEditField = uieditfield(app.PosteriorPanel, 'numeric');
            app.SDPEditField.ValueDisplayFormat = '%.3f';
            app.SDPEditField.ValueChangedFcn = createCallbackFcn(app, @SDPEditFieldValueChanged, true);
            app.AreaPEditFieldLabel = uilabel(app.PosteriorPanel);
            app.AreaPEditFieldLabel.Text = 'Area P';
            app.AreaPEditField = uieditfield(app.PosteriorPanel, 'numeric');
            app.AreaPEditField.ValueDisplayFormat = '%.3f';
            app.AreaPEditField.ValueChangedFcn = createCallbackFcn(app, @AreaPEditFieldValueChanged, true);
            app.IPEditFieldLabel = uilabel(app.PosteriorPanel);
            app.IPEditFieldLabel.Text = 'IP';
            app.IPEditField = uieditfield(app.PosteriorPanel, 'numeric');
            app.IPEditField.ValueDisplayFormat = '%.3f';
            app.IPEditField.ValueChangedFcn = createCallbackFcn(app, @IPEditFieldValueChanged, true);
            app.AdjacentAnteriorPanel = uipanel(app.MeasurementResultsPanel);
            app.AdjacentAnteriorPanel.Title = 'Adjacent Anterior';
            app.MPVBAEditFieldLabel = uilabel(app.AdjacentAnteriorPanel);
            app.MPVBAEditFieldLabel.Text = 'MPV B.A';
            app.MPVBAEditField = uieditfield(app.AdjacentAnteriorPanel, 'numeric');
            app.MPVBAEditField.ValueDisplayFormat = '%.3f';
            app.MPVBAEditField.ValueChangedFcn = createCallbackFcn(app, @MPVBAEditFieldValueChanged, true);
            app.SumBAEditFieldLabel = uilabel(app.AdjacentAnteriorPanel);
            app.SumBAEditFieldLabel.Text = 'Sum B. A';
            app.SumBAEditField = uieditfield(app.AdjacentAnteriorPanel, 'numeric');
            app.SumBAEditField.ValueDisplayFormat = '%.3f';
            app.SumBAEditField.ValueChangedFcn = createCallbackFcn(app, @SumBAEditFieldValueChanged, true);
            app.SDBAEditFieldLabel = uilabel(app.AdjacentAnteriorPanel);
            app.SDBAEditFieldLabel.Text = 'SD B A';
            app.SDBAEditField = uieditfield(app.AdjacentAnteriorPanel, 'numeric');
            app.SDBAEditField.ValueDisplayFormat = '%.3f';
            app.SDBAEditField.ValueChangedFcn = createCallbackFcn(app, @SDBAEditFieldValueChanged, true);
            app.AreaBAEditFieldLabel = uilabel(app.AdjacentAnteriorPanel);
            app.AreaBAEditFieldLabel.Text = 'Area B.A';
            app.AreaBAEditField = uieditfield(app.AdjacentAnteriorPanel, 'numeric');
            app.AreaBAEditField.ValueDisplayFormat = '%.3f';
            app.AreaBAEditField.ValueChangedFcn = createCallbackFcn(app, @AreaBAEditFieldValueChanged, true);
            app.IBAEditFieldLabel = uilabel(app.AdjacentAnteriorPanel);
            app.IBAEditFieldLabel.Text = 'I B.A';
            app.IBAEditField = uieditfield(app.AdjacentAnteriorPanel, 'numeric');
            app.IBAEditField.ValueChangedFcn = createCallbackFcn(app, @IBAEditFieldValueChanged, true);
            app.AdjacentPosteriorPanel = uipanel(app.MeasurementResultsPanel);
            app.AdjacentPosteriorPanel.Title = 'Adjacent Posterior';
            app.MPVBPEditFieldLabel = uilabel(app.AdjacentPosteriorPanel);
            app.MPVBPEditFieldLabel.Text = 'MPV B.P';
            app.MPVBPEditField = uieditfield(app.AdjacentPosteriorPanel, 'numeric');
            app.MPVBPEditField.ValueDisplayFormat = '%.3f';
            app.MPVBPEditField.ValueChangedFcn = createCallbackFcn(app, @MPVBPEditFieldValueChanged, true);
            app.SumBPEditFieldLabel = uilabel(app.AdjacentPosteriorPanel);
            app.SumBPEditFieldLabel.Text = 'Sum B. P';
            app.SumBPEditField = uieditfield(app.AdjacentPosteriorPanel, 'numeric');
            app.SumBPEditField.ValueDisplayFormat = '%.3f';
            app.SumBPEditField.ValueChangedFcn = createCallbackFcn(app, @SumBPEditFieldValueChanged, true);
            app.SDBPEditFieldLabel = uilabel(app.AdjacentPosteriorPanel);
            app.SDBPEditFieldLabel.Text = 'SD B P';
            app.SDBPEditField = uieditfield(app.AdjacentPosteriorPanel, 'numeric');
            app.SDBPEditField.ValueDisplayFormat = '%.3f';
            app.SDBPEditField.ValueChangedFcn = createCallbackFcn(app, @SDBPEditFieldValueChanged, true);
            app.AreaBPEditFieldLabel = uilabel(app.AdjacentPosteriorPanel);
            app.AreaBPEditFieldLabel.Text = 'Area B.P';
            app.AreaBPEditField = uieditfield(app.AdjacentPosteriorPanel, 'numeric');
            app.AreaBPEditField.ValueDisplayFormat = '%.3f';
            app.AreaBPEditField.ValueChangedFcn = createCallbackFcn(app, @AreaBPEditFieldValueChanged, true);
            app.IBPEditFieldLabel = uilabel(app.AdjacentPosteriorPanel);
            app.IBPEditFieldLabel.Text = 'I B.P';
            app.IBPEditField = uieditfield(app.AdjacentPosteriorPanel, 'numeric');
            app.IBPEditField.ValueChangedFcn = createCallbackFcn(app, @IBPEditFieldValueChanged, true);
            app.ClearResultsButton = uibutton(app.MeasurementResultsPanel, 'push');
            app.ClearResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearResultsButtonPushed, true);
            app.ClearResultsButton.Text = 'Clear Results';
            app.ScanDurationEditFieldLabel = uilabel(app.UIFigure);
            app.ScanDurationEditFieldLabel.Text = 'Scan Duration ';
            app.ScanDurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.ScanDurationEditField.ValueDisplayFormat = '%.3f';
            app.ScanDurationEditField.ValueChangedFcn = createCallbackFcn(app, @ScanDurationEditFieldValueChanged, true);
            app.OrganEditFieldLabel = uilabel(app.UIFigure);
            app.OrganEditFieldLabel.Text = 'Organ ';
            app.OrganEditField = uieditfield(app.UIFigure, 'text');
            app.OrganEditField.ValueChangedFcn = createCallbackFcn(app, @OrganEditFieldValueChanged, true);
            app.SeeformulaButton = uibutton(app.UIFigure, 'push');
            app.SeeformulaButton.ButtonPushedFcn = createCallbackFcn(app, @SeeformulaButtonPushed, true);
            app.SeeformulaButton.Text = 'See formula ';
            app.ActivityEditFieldLabel = uilabel(app.UIFigure);
            app.ActivityEditFieldLabel.Text = 'Activity  ';
            app.ActivityEditField = uieditfield(app.UIFigure, 'numeric');
            app.ActivityEditField.ValueDisplayFormat = '%.3f';
            app.ActivityEditField.ValueChangedFcn = createCallbackFcn(app, @ActivityEditFieldValueChanged, true);
            app.TimePointEditFieldLabel = uilabel(app.UIFigure);
            app.TimePointEditFieldLabel.Text = 'Time Point';
            app.TimePointEditField = uieditfield(app.UIFigure, 'text');
            app.TimePointEditField.ValueChangedFcn = createCallbackFcn(app, @TimePointEditFieldValueChanged, true);
            app.ZoomSwitchLabel = uilabel(app.UIFigure);
            app.ZoomSwitchLabel.Text = 'Zoom';
            app.ZoomSwitch = uiswitch(app.UIFigure, 'slider');
            app.ZoomSwitch.ValueChangedFcn = createCallbackFcn(app, @ZoomSwitchValueChanged, true);
            app.FlippedAnteriorPosteriorPanel = uipanel(app.UIFigure);
            app.FlippedAnteriorPosteriorPanel.Title = 'Flipped Anterior - Posterior';
            app.UIAxes = uiaxes(app.FlippedAnteriorPosteriorPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Icon = 'UI-vpj-mono-b.png';
            app.Button.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end